classdef NJApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ControlPanel     matlab.ui.container.Panel
        GridLayout       matlab.ui.container.GridLayout
        Button3          matlab.ui.control.Button
        STARTButton      matlab.ui.control.Button
        LOADEXCELButton  matlab.ui.control.Button
        word_label       matlab.ui.control.Label
        pick_answer_box  matlab.ui.control.ListBox
        SlovoLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        words_table
        selected_category

        current_table

        word_is_picking
    end
    
    
    methods (Access = private)
        
        function loadWords(app) 
            app.words_table = readtable("Words.xlsx");
        end

        function startQuiz(app)
            %Category selections
            selected_rows = strcmp(app.words_table.('Kategorie'), app.selected_category);
            app.current_table = app.words_table(selected_rows, :);
            
            index_array = randperm(size(app.current_table,1));
            app.word_is_picking = 1;
            for i = index_array
                current_czech_word = app.current_table.('Ceske_slovo'){i};
                current_german_word = app.current_table.('Nemecke_slovo'){i};
                app.word_label.Text = current_czech_word;
                while(app.word_is_picking)
                    pause(0.01)
                end
                app.word_is_picking = 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Load words from txts
            app.loadWords();
            app.selected_category = 'animals';
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.startQuiz()
        end

        % Value changed function: pick_answer_box
        function pick_answer_boxValueChanged(app, event)
            app.word_is_picking = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SlovoLabel = uilabel(app.UIFigure);
            app.SlovoLabel.Text = 'Slovo:';
            app.pick_answer_box = uilistbox(app.UIFigure);
            app.pick_answer_box.ValueChangedFcn = createCallbackFcn(app, @pick_answer_boxValueChanged, true);
            app.word_label = uilabel(app.UIFigure);
            app.word_label.Text = '';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.GridLayout = uigridlayout(app.ControlPanel);
            app.LOADEXCELButton = uibutton(app.GridLayout, 'push');
            app.LOADEXCELButton.Text = 'LOAD EXCEL';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Button3 = uibutton(app.GridLayout, 'push');
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NJApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end